classdef apcam < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure        matlab.ui.Figure
        StartCamButton  matlab.ui.control.Button
        Image           matlab.ui.control.Image
        EndButton       matlab.ui.control.Button
        BrowseButton    matlab.ui.control.Button
    end

    
    properties (Access = public)
        cam = 0% Description
        capture  = false
  
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: StartCamButton
        function StartCamButtonPushed(app, event)
            
            
            count = 0;
            app.cam = webcam(1);
            app.capture = true;
            while app.capture == true
                frame = snapshot(app.cam);
                 frame =imresize(frame,0.25);
                frame = flip(frame,2);
               imwrite(frame,strcat("videocam\saved",int2str(count),".png"));
                [cframe,yframe,cbframe,crframe] = encoding(frame);
%               imwrite(cframe,strcat("livevideo\cframe",int2str(count),".png"));
%               imwrite(yframe,strcat("videocam\savedY",int2str(count),".png"));
%                  imwrite(cbframe,strcat("videocam\savedcb",int2str(count),".png"));
%                  imwrite(crframe,strcat("videocam\savedcr",int2str(count),".png"));
                 savebin(yframe,cbframe,crframe,count);
                count=count+1;
                app.Image.ImageSource = frame;
                
                if count == 10
                    app.capture = false;
                    clear app.cam
                end
            end
%             count = 0;
%             
%             while app.capture == true
%                 frame = snapshot(app.cam);
%                 
% %                  frame = flip(frame,2);
%                     frame = imresize(frame,0.25);
%                      imwrite(frame,strcat("livevideorgb\saved",int2str(count),".png"));
%                     frame = frame(:,end:-1:1,:);
%                  [cframe,yframe,cbframe,crframe] = encoding(frame);
% %                  imwrite(cframe,strcat("livevideo\saved",int2str(count),".png"));
%                 imwrite(yframe,strcat("livevideo\savedY",int2str(count),".png"));
%                 imwrite(cbframe,strcat("livevideo\savedcb",int2str(count),".png"));
%                 imwrite(crframe,strcat("livevideo\savedcr",int2str(count),".png"));  
%                 count = count + 1;
%                 app.Image.ImageSource = frame;
%                 if count == 10
%                     app.capture = false;
%                     clear app.cam
%                 end
%             end
%             clear app.cam
        end

        % Button pushed function: EndButton
        function EndButtonPushed(app, event)
            app.capture = false;
            clear app.cam
        end

        % Button pushed function: BrowseButton
        function BrowseButtonPushed(app, event)
            inputDir=uigetdir;
%             pngFilesY = dir(strcat(inputDir,'\*Y*.png'));
%             pngFilescb = dir(strcat(inputDir,'\*Cb*.png'));
%             pngFilescr = dir(strcat(inputDir,'\*Cr*.png'));
            
            pngFilesY = dir(strcat(inputDir,'\*Y*.bin'));
            pngFilescb = dir(strcat(inputDir,'\*Cb*.bin'));
            pngFilescr = dir(strcat(inputDir,'\*Cr*.bin'));
            pngFilesY.folder
            % 
            count = length(pngFilesY);
            prog = waitbar(0,"wait");
            for i = 1:count  
%                framey = imread(strcat( pngFilesY(i).folder,'\', pngFilesY(i).name));
%                 framecb = imread(strcat( pngFilescb(i).folder,'\', pngFilescb(i).name));
%                framecr = imread(strcat( pngFilescr(i).folder,'\', pngFilescr(i).name));
               framey = readbin(strcat( pngFilesY(i).folder,'\', pngFilesY(i).name),320,180);
               framecb = readbin(strcat( pngFilescb(i).folder,'\', pngFilescb(i).name),320,180);
               framecr = readbin(strcat( pngFilescr(i).folder,'\', pngFilescr(i).name),320,180);


%                 writeVideo(outvideo,frame);

                imtool(framey)
                frame = decoding(framey,framey,framecb,framecr);
                waitbar(i/count,prog,"Processing");
%                I = rgb2gray(frame);
%                 frame(:,:,1) = I;
%                 frame(:,:,2) = I;
%                 frame(:,:,3) = I;
                app.Image.ImageSource = frame;
            end
            waitbar(1,prog,"done");
            close(prog);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.StartCamButton = uibutton(app.UIFigure, 'push');
            app.StartCamButton.ButtonPushedFcn = createCallbackFcn(app, @StartCamButtonPushed, true);
            app.StartCamButton.Text = 'Start Cam';
            app.Image = uiimage(app.UIFigure);
            app.EndButton = uibutton(app.UIFigure, 'push');
            app.EndButton.ButtonPushedFcn = createCallbackFcn(app, @EndButtonPushed, true);
            app.EndButton.Text = 'End';
            app.BrowseButton = uibutton(app.UIFigure, 'push');
            app.BrowseButton.ButtonPushedFcn = createCallbackFcn(app, @BrowseButtonPushed, true);
            app.BrowseButton.Text = 'Browse';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = apcam

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end